classdef A_UI_RuptureData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        BinNumber                matlab.ui.control.NumericEditField
        BinNumberStartsat1EnterandPressEnterLabel  matlab.ui.control.Label
        WorkLabel                matlab.ui.control.Label
        FileLoadDropDown         matlab.ui.control.DropDown
        LoadButton               matlab.ui.control.Button
        FileLoadDropDown_2       matlab.ui.control.DropDown
        LoadButton_2             matlab.ui.control.Button
        FileLoadDropDown_3       matlab.ui.control.DropDown
        FileLoadDropDown_3Label  matlab.ui.control.Label
        LoadButton_3             matlab.ui.control.Button
        WorkEditField            matlab.ui.control.NumericEditField
        FileLoadDropDown_2Label  matlab.ui.control.Label
        FileLoadDropDownLabel    matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        Work                     matlab.ui.control.UIAxes
        FilteredForces           matlab.ui.control.UIAxes
        ForceData                matlab.ui.control.UIAxes
        Histogram                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        extractedData % Global Variables
        posForceData
        trapStiffness
        binForces
    end
    
    methods (Access = private)
        
   
        %% Dropdown Menu (Organize and display files)
        function populateDropdown(app, filePattern, dropdown)

            files = dir(filePattern); 
            fileNames = {files.name}; % Extract file names

            if isempty(fileNames) % If the files selected do not have data within it 
                dropdown.Items = {'No files found'};
                dropdown.Value = 'No files found';
                return;
            end
            
            %This entire section extracts and sort the numeric parts of the
            %file and then sorts them such that it follows numerically
            numericParts = zeros(length(fileNames),1);
            for i = 1:length(fileNames)
                match = regexp(fileNames{i}, '_([\d]+)\.mat$', 'tokens');
                if ~isempty(match)
                    numericParts(i) = str2double(match{1}{1});
                else
                    numericParts(i) = Inf; % Assign infinity if no match so it remains at the end
                end
            end
            [~, order] = sort(numericParts); % Sorts the numeric parts
            sortedFileNames = fileNames(order);
    
            % Populate the dropdown with sorted file names
            dropdown.Items = sortedFileNames;
            dropdown.Value = sortedFileNames{1};
        end
       
        % Function to load data and plot it into the app
        function loadDataAndPlot(app, selectedFile)
            
            % Variable Generation
            data = load(selectedFile); % Extracts data from the selectedFile
            forceData = data.F;  % Assuming 'F' is the force data
            app.trapStiffness = data.k; % Assuming "k" is the spring constant
            time = 0:(length(forceData) - 1);  % Generate time points
            N = length(forceData); % Length
            ts = 0.01; % Sampling time
            fs = 1/ts;
            X = fft(forceData); % Fourier Transform
            X = abs(X/N);

            % Fourier Transform & Low Pass Filtering
            fc = 5/fs; 
            [b, a] = butter(4, fc/(fs/2), 'low'); % Low pass filter
            y = filter(b, a, forceData);        
        
            % Plot Force Data
            plot(app.ForceData, time, forceData);
            xlabel(app.ForceData, 'Time (s)');
            ylabel(app.ForceData, 'Force (pN)');
            title(app.ForceData, ['Force Data - ', selectedFile]);
        
            % Plot Filtered Force Data
            plot(app.FilteredForces,time,y);
            title(app.FilteredForces, ['Filtered Forces - ', selectedFile]); % Name and then labels the specific data name such that the graph title is specific to the data set
            xlabel(app.FilteredForces, 'Time (s)');
            ylabel(app.FilteredForces, 'Force (pN)');

            % Plot Histogram
            app.posForceData = forceData(forceData > 0); % Isolate only positive forces
            normalizedForce = (app.posForceData - min(app.posForceData)) / range(app.posForceData) * max(app.posForceData);
            binWidth = 0.7; % Set binWidth. Smaller the bins = larger resolution but skipping occurs
            binEdges = min(app.posForceData):binWidth:max(app.posForceData); %Generating edges for histogram bins: beginning and increments in binWidth until max force
            binCounts = histcounts(normalizedForce, binEdges); % Count the number of normalized forces that fall within each bin
            probDensity = binCounts / (sum(binCounts) * binWidth); %Convert the bin counts into a probability density
            binCenters = binEdges(1:end-1); % Calculate the number of center positions of each histogram bin
                
                % Finding Max Peaks to Highlight them in Histograms 
                app.binForces = [binCenters;probDensity];
                k = 1;
                a = 1;
                maxIndexes = [];
                lookingForPeak = true; 
                
                while k < length(app.binForces) - 1
                    if lookingForPeak
                        % Look for a peak
                        if app.binForces(2, k) > app.binForces(2, k + 1)
                            maxIndexes(a) = k;
                            a = a + 1;
                            lookingForPeak = false; % Switch to looking for a trough
                        end
                    else
                        % Look for a trough
                        if app.binForces(2, k) < app.binForces(2, k + 1)
                            lookingForPeak = true; % Switch to looking for a peak
                        end
                    end
                    k = k + 1;
                end

            % Creating our histogram graph
            b = bar(app.Histogram, binCenters, probDensity, 'FaceColor', 'flat'); % We want to specify the color of the histogram
            xlabel(app.Histogram, 'Force(pN');
            ylabel(app.Histogram, 'Probablity Density (1/pN)');
            title(app.Histogram, ['Histogram - ', selectedFile]);
            xlim(app.Histogram,[0 max(forceData)]);
            ylim(app.Histogram,[0 max(probDensity)*1.1]);

            for k = 1:length(maxIndexes)
                b.CData(maxIndexes(k),:) = [1,0,0]; %Highlights the peaks in red
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

      %{
        Within the folder, this code below finds the files that have the
        same inital letters and then loads them into the loadDataAndPlot
        function above via a pushed button since by selecting a file and
        having it load automatically could lead to multiple complications
        of multiple files loading at once
      %}    
        app.populateDropdown('WT_BB94_*.mat', app.FileLoadDropDown); 
        
        app.populateDropdown('HAN1_DMSO_*.mat', app.FileLoadDropDown_2);

        app.populateDropdown('WT_FI_*.mat', app.FileLoadDropDown_3);
     
    
        end

        % Button pushed function: LoadButton
        function ButtonPushed_WT_BB94(app, event)
            app.loadDataAndPlot(app.FileLoadDropDown.Value); %The pushed button of this will grab the file name selected and then push into the main function
        end

        % Button pushed function: LoadButton_2
        function ButtonPushed_HAN1_DMSO(app, event)
            app.loadDataAndPlot(app.FileLoadDropDown_2.Value);

        end

        % Button pushed function: LoadButton_3
        function ButtonPushed_WT_Fl(app, event)
            app.loadDataAndPlot(app.FileLoadDropDown_3.Value);

        end

        % Value changed function: BinNumber
        function BinNumberValueChanged(app, event)
            % In this function we are creating the extension (more like
            % distance vs force graph which is a traditiona work graph
            
            binNumber = app.BinNumber.Value;

            firstBondForcePeak = app.binForces(1,binNumber); 
            DFdata = app.posForceData;
            distanceData = DFdata./app.trapStiffness; 
            % It takes the force data and then using the trap stiffness 
            % assigned in the data it then divides the force by the
            % stiffness to get pure distance

            DFdata = [distanceData,DFdata]; % Create table
            DFdata = sortrows(DFdata,1); % Sorts the rows to be in order
            
            xData = DFdata(:,1); % Specifies what data is which such that we can graph those
            yData = DFdata(:,2);
            
            % Plot the main data
            plot(app.Work, xData, yData);
            xlabel(app.Work, 'Extension (um)');
            ylabel(app.Work, 'Force (pN)');
            title(app.Work, "Extension Vs Force Graph");


        end

        % Changes arrangement of the app based on UIFigure width
        function appUpdate(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {205, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @appUpdate, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FileLoadDropDownLabel = uilabel(app.LeftPanel);
            app.FileLoadDropDownLabel.Text = 'File Load';
            app.FileLoadDropDown_2Label = uilabel(app.LeftPanel);
            app.FileLoadDropDown_2Label.Text = 'File Load';
            app.WorkEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WorkEditField.Editable = 'off';
            app.LoadButton_3 = uibutton(app.LeftPanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_WT_Fl, true);
            app.LoadButton_3.Text = 'Load';
            app.FileLoadDropDown_3Label = uilabel(app.LeftPanel);
            app.FileLoadDropDown_3Label.Text = 'File Load';
            app.FileLoadDropDown_3 = uidropdown(app.LeftPanel);
            app.LoadButton_2 = uibutton(app.LeftPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_HAN1_DMSO, true);
            app.LoadButton_2.Text = 'Load';
            app.FileLoadDropDown_2 = uidropdown(app.LeftPanel);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_WT_BB94, true);
            app.LoadButton.Text = 'Load';
            app.FileLoadDropDown = uidropdown(app.LeftPanel);
            app.WorkLabel = uilabel(app.LeftPanel);
            app.WorkLabel.Text = 'Work:';
            app.BinNumberStartsat1EnterandPressEnterLabel = uilabel(app.LeftPanel);
            app.BinNumberStartsat1EnterandPressEnterLabel.Text = {'Bin Number'; '(Starts at 1) '; 'Enter # and '; 'Press Enter'};
            app.BinNumber = uieditfield(app.LeftPanel, 'numeric');
            app.BinNumber.ValueChangedFcn = createCallbackFcn(app, @BinNumberValueChanged, true);
            app.BinNumber.Value = 0.5;
            app.RightPanel = uipanel(app.GridLayout);
            app.Histogram = uiaxes(app.RightPanel);
            title(app.Histogram, 'Histogram')
            xlabel(app.Histogram, 'Force (pN)')
            ylabel(app.Histogram, 'Probability Density')
            zlabel(app.Histogram, 'Z')
            app.ForceData = uiaxes(app.RightPanel);
            title(app.ForceData, 'Force Data')
            xlabel(app.ForceData, 'Time(s)')
            ylabel(app.ForceData, 'Force (pN)')
            zlabel(app.ForceData, 'Z')
            app.FilteredForces = uiaxes(app.RightPanel);
            title(app.FilteredForces, 'Filtered Data')
            xlabel(app.FilteredForces, 'Time(s)')
            ylabel(app.FilteredForces, 'Force (pN)')
            zlabel(app.FilteredForces, 'Z')
            app.Work = uiaxes(app.RightPanel);
            title(app.Work, 'Work')
            xlabel(app.Work, 'Distance (um)')
            ylabel(app.Work, 'Force (pN)')
            zlabel(app.Work, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A_UI_RuptureData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end